classdef GWAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        CalculateButton     matlab.ui.control.Button
        UITable             matlab.ui.control.Table
        LoadSNPsInfoButton  matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        Image               matlab.ui.control.Image
        CaGeAppGWASLabel    matlab.ui.control.Label
        VUT_logo            matlab.ui.control.Image
    end

properties (Access = private)
        MainApp;
        rawFileName;  % Name of file where data is located
        rawFilePath;  % Name of file path
        Geno;        % Genomic data
        Phe;
        InfoSNP;
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, geno_info, pheno_info)
            app.Geno = geno_info; % Genomic data
            app.Phe = pheno_info; % Phenotype data
            
            %Store main app in property
            app.MainApp = main_app;

        end

        % Button pushed function: LoadSNPsInfoButton
        function LoadSNPsInfoButtonPushed(app, event)
        [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 app.InfoSNP = readtable(strcat(app.rawFilePath,app.rawFileName));
                 app.UITable.Data = app.InfoSNP;
                 varHeadTable = app.UITable.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITable.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = warndlg(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end     
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            data = outerjoin(app.Geno, app.Phe, 'MergeKeys', true);

            % Perform association testing for each SNP
            results = table();
            genotype_data_columns = app.Geno.Properties.VariableNames;
            genotype_data_columns = genotype_data_columns(~strcmp(genotype_data_columns, 'Ind'));
            
            for i = 1:length(genotype_data_columns)
                snp = genotype_data_columns{i};
                x = data.(snp);
                x = [ones(size(x)), x];
                y = data.phenotype;  % Corrected line
                [beta,~,~,~,stats] = regress(y, x);  % Fit linear regression model
                beta = beta(2);  % Coefficient represents effect size
                p_value = stats(3);  % Calculation p-value
                results = [results; table({snp}, p_value, beta)]; % Use cell array for the SNP variable
            end
            
            effect_sizes = results(:, {'Var1', 'beta'});
            results = removevars(results, 'beta');
          
            % Merge SNP information data with results
            app.InfoSNP.Properties.VariableNames(1) = "Var1";
            manhattan_data = innerjoin(results, app.InfoSNP(:, {'Var1', 'Chromosome', 'Position'}), 'Keys', 'Var1');
            
            % Calculate -log10(P-value)
            manhattan_data.NegLogP = -log10(manhattan_data.p_value);
            
            % Plot Manhattan plot
            colors = hsv(max(manhattan_data.Chromosome)); % Define colors for each chromosome
            cumulative_max_position = 0; % Initialize cumulative maximum position
            for chr = unique(manhattan_data.Chromosome)'
                chr_data = manhattan_data(manhattan_data.Chromosome == chr, :);
                scatter(app.UIAxes, chr_data.Position + cumulative_max_position, chr_data.NegLogP, 20, colors(chr, :), 'filled', 'DisplayName', ['Chromosome ' num2str(chr)]);
                hold(app.UIAxes, 'on');
                cumulative_max_position = cumulative_max_position + max(chr_data.Position); % Update cumulative maximum position
            end
            hold(app.UIAxes, 'off');
            xlabel(app.UIAxes, 'Genomic Position');
            ylabel(app.UIAxes, '-log10(P-Value)');
            title(app.UIAxes, 'Manhattan Plot for GWAS');
            legend(app.UIAxes, 'Location', 'bestoutside');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VUT_logo = uiimage(app.UIFigure);
            app.VUT_logo.ImageSource = 'BUT_logo_T.png';
            app.CaGeAppGWASLabel = uilabel(app.UIFigure);
            app.CaGeAppGWASLabel.Text = ' CaGeApp – GWAS';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'MoSys_logo.png');
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadSNPsInfoButton = uibutton(app.Panel, 'push');
            app.LoadSNPsInfoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSNPsInfoButtonPushed, true);
            app.LoadSNPsInfoButton.Text = 'Load SNPs Info';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GWAS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end